classdef US_DICOM_Renamer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UltrasoundRenameToolUIFigure  matlab.ui.Figure
        ForwardButton                 matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        ReviseROIButton               matlab.ui.control.Button
        ShowROICheckBox               matlab.ui.control.CheckBox
        OCRImageNameTextArea          matlab.ui.control.TextArea
        OCRImageNameLabel             matlab.ui.control.Label
        ImageNameTextArea             matlab.ui.control.TextArea
        ImageNameTextAreaLabel        matlab.ui.control.Label
        NewImageNameEditField         matlab.ui.control.EditField
        NewImageNameLabel             matlab.ui.control.Label
        ParticipantIDTextArea         matlab.ui.control.TextArea
        ParticipantIDTextAreaLabel    matlab.ui.control.Label
        CurrentImageTextArea          matlab.ui.control.TextArea
        CurrentImageTextAreaLabel     matlab.ui.control.Label
        TotalImagesTextArea           matlab.ui.control.TextArea
        TotalImagesTextAreaLabel      matlab.ui.control.Label
        RenameButton                  matlab.ui.control.Button
        SelectUltrasoundDirectoryButton  matlab.ui.control.Button
        ImageAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image_path; % Variable with path/folder containing ultrasound/DICOM images to rename
        US_Image; % Variable to current ultrasound/DICOM image being renamed
        info; % Variable to hold DICOM meta-data from opened ultrasound/DICOM image

        filelist; % Variable to setup list with all ultrasound/DICOM images to rename
        renameTracker = 1; % Variable to track how many images have been renamed from total set in fileList
        fileName; % Variable to hold path for ultrasound/DICOM image to be renamed
        newFilename; % Variable for new name (if modified from string determined by computer vision)

        roi = [1, 50, 896, 500]; % Region of Interest (ROI) to use for initial computer vision detection of text on image
        roi_rectangle; % Variable for user-selectable rectangle to modify ROI

        summary; % Variable linked to output text file which details renamed files from session
        version = 2.0; % Variable to track software version
    end

    methods (Access = private)

        function US_desc = ImageStringDetect(app)
            % Description: This function manipulates the ultrasound image
            % to make it grey-scale, inverts the colors (so the text is
            % a dark color on a white background), uses optical character
            % recognition (OCR) tools to detect text within a region of
            % interest (ROI), and then outputs the identified string as a
            % potential file-name to rename the image

            Igray = im2gray(app.US_Image);
            Ibinary = imbinarize(Igray);
            Icomplement = imcomplement(Ibinary);
            output = ocr(Icomplement,app.roi);
            US_desc = strip(output.Text);
            US_desc = splitlines(US_desc);
            US_desc = US_desc{1};
            US_desc = regexprep(US_desc, ' ', '_');
        end
        
        function BadCharacterRemoval(app)
            BadChar = '<>:"|?*!@#$%^&()[]{}|';
            
            bad = ismember(BadChar, app.newFilename);

            if any(bad)
                message = ["Name contains bad characters: ", BadChar(bad)];
                uialert(app.UltrasoundRenameToolUIFigure, message, "Warning","Icon","warning");
            end

            for i = 1:1:length(BadChar)
                app.newFilename = erase(app.newFilename, BadChar(i));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectUltrasoundDirectoryButton
        function SelectUltrasoundDirectoryButtonPushed(app, event)
            % Description: Function to scan directory selected by user to
            % and create fileList of all DICOM/ultrasound images for
            % renaming.

            % Get directory for renaming from user
            app.image_path = uigetdir('', "Select ultrasound image directory");
            app.renameTracker = 1;

            % Navigate to renaming directory, get list of all files in
            % directory (exclude directories from listing)
            cd(app.image_path)
            list = dir('**/*');
            dirFlags = [list.isdir];
            fileFlags = ~dirFlags;

            app.filelist = list(fileFlags);

            % Clean up file-list to remove potential hidden files and
            % files, text files, or non-DICOM images from list.  Cannot use
            % file extension because not all DICOM images have a file
            % extension
            fileLen = length(app.filelist);
            for i = 1:1:length(fileLen)

                % Approach to check file sizes and only include files
                % greater than 10000 bytes.  DiCOM images are MB in
                % size, so this throws out hidden informational files
                if(app.filelist(i).bytes < 10000)
                    app.filelist(i) = [];
                    fileLen = fileLen - 1;
                end
            end
           
            app.summary = fopen("summary.txt", 'at+' );
            fprintf(app.summary, "Rename Session Start: ");
            fprintf(app.summary, string(datetime));
            fprintf(app.summary, '\n');
            fclose(app.summary);

            % Open current ultrasound/DICOM image to get meta-data (info),
            % image data (US_Image), and show image data in GUI
            while(app.renameTracker < fileLen)
                try
                    % Setting path/name for ultrasound/DICOM image to view and
                  % rename
                    if(ispc)
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "\", app.filelist(app.renameTracker).name);
                    else
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "/", app.filelist(app.renameTracker).name);
                    end

                    app.info = dicominfo(app.fileName);
                    app.US_Image = dicomread(app.info);
                    break;
                catch
                    warning(strcat('Problem opening: ', app.fileName));
                    app.summary = fopen("summary.txt", 'at+' );
                    fprintf(app.summary, "Error Opening File: ");
                    fprintf(app.summary, app.filelist(app.renameTracker).name);
                    fprintf(app.summary, " (File Skipped)");
                    fprintf(app.summary, '\n');
                    fclose(app.summary);
                    app.filelist(app.renameTracker) = [];
                    fileLen = fileLen - 1;
                end
            end

            imshow(app.US_Image,'Parent',app.ImageAxes);

            % Create ptoential filename using OCR detected string from
            % ultrasound image and remove characters that could cause
            % saving issues in filesystems
            US_desc = ImageStringDetect(app);
            app.newFilename = strcat(app.info.PatientID, "_", US_desc);
            BadCharacterRemoval(app);

            % Update the GUI with information about the overall renaming
            % process.
            %   PatientID: patient identifier from DICOM meta-data which
            %   will be used as the prefix to rename the file
            %   CurrentImageText: Current images out of total images to give
            %   indicator of renaming progress
            %   TotalImages: Total images to be renamed
            %   ImageName: Existing filename of image to rename
            app.ParticipantIDTextArea.Value = app.info.PatientID;
            app.CurrentImageTextArea.Value = num2str(app.renameTracker);
            app.TotalImagesTextArea.Value = num2str(length(app.filelist));
            app.ImageNameTextArea.Value = app.filelist(app.renameTracker).name;

            % Update the GUI with information about the new name
            % OCRImageName: Suggested name based on text identified in
            % image using the optical character recognition tool
            % NewImageNameEdit: Suggested start of new name using
            % ParticipantID, to be used in cases where OCR fails / no text
            % is present on the image to use
            app.OCRImageNameTextArea.Value = app.newFilename;
            app.NewImageNameEditField.Value = strcat(app.info.PatientID, "_");

        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
        % Description: Function to complete renaming actions which
        % includes:
        %   1) Setup new directories to sort renamed images by participant
        %   2) Check filenames for characters that may cause problems
        %   3) Check filename is unique so previous images not overwritten
        %   4) Copy DICOM/ultrasound image to new directory with new name
        %   5) Open next file for viewing / naming

        % Checking if a directory to store the renamed files exists, if not
        % creates it
            if not(isfolder("Renamed"))
                mkdir Renamed
            end

            if(ispc)
                renameStr = "Renamed\";
                renameStr2 = "\Renamed\";
            else
                renameStr = "Renamed/";
                renameStr2 = "/Renamed/";
            end
        
        % Checking if directory for unique participant exists to save
        % renamed file, if not creates it
            if not(isfolder(strcat(renameStr, app.info.PatientID)))
                mkdir( strcat(renameStr, app.info.PatientID));
            end

            if(~strcmp(app.NewImageNameEditField.Value, strcat(app.info.PatientID, "_")))
                app.newFilename = app.NewImageNameEditField.Value;
            end

            BadCharacterRemoval(app);

            % Check if the filename that will be used to rename the current
            % image exists already in the directory, if it does revise name
            % to include _X where X is a number to differentiate it from
            % the existing filename.
            % This code also searches the directory for other _X filenames
            % and finds an appropriate value to again prevent overwriting a
            % file.  Currently limited to having 5000 files with the same
            % name.
            if exist(strcat(app.image_path, renameStr2, app.info.PatientID, "/", app.newFilename) , 'file') == 2
                for N = 2:1:5000
                    if exist(strcat(app.image_path, renameStr2, app.info.PatientID, "/", app.newFilename, "_", num2str(N)), 'file') == 2
                    else
                        app.newFilename = strcat(app.newFilename, "_", num2str(N));
                        message = ["Duplicate Filename",strcat("Revised to: ", app.newFilename) ];
                        uialert(app.UltrasoundRenameToolUIFigure,message,"Warning","Icon","warning");
                        break;
                    end
                end
            end

            % Copy ultrasound/DICOM file to new folder (based on
            % participant identified) with new filename
            copyfile(app.fileName, strcat(app.image_path, renameStr2, app.info.PatientID, "/", app.newFilename));
            app.summary = fopen("summary.txt", 'at+' );
            fprintf(app.summary, "DICOM File: ");
            fprintf(app.summary, app.filelist(app.renameTracker).name);
            fprintf(app.summary, " copied/renamed ");
            fprintf(app.summary, app.newFilename);
            fprintf(app.summary, '\n');
            fclose(app.summary);


            app.renameTracker = app.renameTracker + 1;
            if(app.renameTracker > length(app.filelist))
                message = "All files renamed! You can close the app now.";
                uialert(app.UltrasoundRenameToolUIFigure,message,"success","Icon","success");
                
                app.summary = fopen("summary.txt", 'at+' );
                fprintf(app.summary, "Rename Session Close: ");
                fprintf(app.summary, string(datetime));
                fprintf(app.summary, '\n');
                fclose(app.summary);
                return;
            end

            % Open current ultrasound/DICOM image to get meta-data (info),
            % image data (US_Image), and show image data in GUI
            fileLen = length(app.filelist);
            while(app.renameTracker < fileLen)
                try
                    if(ispc)
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "\", app.filelist(app.renameTracker).name);
                    else
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "/", app.filelist(app.renameTracker).name);
                    end

                    app.info = dicominfo(app.fileName);
                    app.US_Image = dicomread(app.info);
                    break;
                catch
                    warning(strcat('Problem opening: ', app.fileName));
                    app.summary = fopen("summary.txt", 'at+' );
                    fprintf(app.summary, "Error Opening: ");
                    fprintf(app.summary, app.filelist(app.renameTracker).name);
                    fprintf(app.summary, " (File Skipped)");
                    fprintf(app.summary, '\n');
                    fclose(app.summary);
                    app.filelist(app.renameTracker) = [];
                    fileLen = fileLen - 1;
                end
            end
            imshow(app.US_Image,'Parent',app.ImageAxes);

            % Analyze image for text that can be used to rename current
            % image
            US_desc = ImageStringDetect(app);
            
            % Create potential filename using OCR detected string from
            % ultrasound image and remove characters that could cause
            % saving issues in filesystems
            app.newFilename = strcat(app.info.PatientID, "_", US_desc);
            BadCharacterRemoval(app);
            
            % Update the GUI with information about the overall renaming
            % process.
            %   PatientID: patient identifier from DICOM meta-data which
            %   will be used as the prefix to rename the file
            %   CurrentImageText: Current images out of total images to give
            %   indicator of renaming progress
            %   TotalImages: Total images to be renamed
            %   ImageName: Existing filename of image to rename
            app.ParticipantIDTextArea.Value = app.info.PatientID;
            app.CurrentImageTextArea.Value = num2str(app.renameTracker);
            app.TotalImagesTextArea.Value = num2str(length(app.filelist));
            app.ImageNameTextArea.Value = app.filelist(app.renameTracker).name;


            % Update the GUI with information about the new name
            % OCRImageName: Suggested name based on text identified in
            % image using the optical character recognition tool
            % NewImageNameEdit: Suggested start of new name using
            % ParticipantID, to be used in cases where OCR fails / no text
            % is present on the image to use
            app.OCRImageNameTextArea.Value = app.newFilename;
            app.NewImageNameEditField.Value = strcat(app.info.PatientID, "_");

            % If ROI check-box is selected, draw red rectangle on screen
            % that shows user the area in the image that is the region of
            % interest for the optical character detection function
            if(app.ShowROICheckBox.Value == 1)
                app.roi_rectangle = drawrectangle(app.ImageAxes,'Position',app.roi,'Color','r');
            else
                delete(app.roi_rectangle);
            end

        end

        % Value changed function: ShowROICheckBox
        function ShowROICheckBoxValueChanged(app, event)
            value = app.ShowROICheckBox.Value;

            if(value == 1)
                app.roi_rectangle = drawrectangle(app.ImageAxes,'Position',app.roi,'Color','r');
            else
                delete(app.roi_rectangle);
            end
        end

        % Button pushed function: ReviseROIButton
        function ReviseROIButtonPushed(app, event)
            % Description: Provides option to draw new rectangular region
            % of interest to be used in the optical character detection
            % process.
            
            delete(app.roi_rectangle);
            app.roi_rectangle = drawrectangle(app.ImageAxes, 'Color','r');
            app.roi = app.roi_rectangle.Position;
            app.ShowROICheckBox.Value = 1;

            % Analyze image for text that can be used to rename current
            % image
            US_desc = ImageStringDetect(app);
            app.newFilename = strcat(app.info.PatientID, "_", US_desc);
            BadCharacterRemoval(app);

            % Update the GUI with information about the new name
            % OCRImageName: Suggested name based on text identified in
            % image using the optical character recognition tool
            % NewImageNameEdit: Suggested start of new name using
            % ParticipantID, to be used in cases where OCR fails / no text
            % is present on the image to use
            app.OCRImageNameTextArea.Value = app.newFilename;
            app.NewImageNameEditField.Value = strcat(app.info.PatientID, "_");

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Description: Provides functionality to navigate through
            % previous images in the overall filelist without having to
            % rename them

            if(app.renameTracker == 1)
                app.renameTracker = length(app.filelist);
            else
                app.renameTracker = app.renameTracker - 1;
            end

            % Open current ultrasound/DICOM image to get meta-data (info),
            % image data (US_Image), and show image data in GUI
            while(app.renameTracker > 1)
                try
                    if(ispc)
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "\", app.filelist(app.renameTracker).name);
                    else
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "/", app.filelist(app.renameTracker).name);
                    end

                    app.info = dicominfo(app.fileName);
                    app.US_Image = dicomread(app.info);
                    break;
                catch
                    warning(strcat('Problem opening: ', app.fileName));
                    app.summary = fopen("summary.txt", 'at+' );
                    fprintf(app.summary, "Error Opening: ");
                    fprintf(app.summary, app.filelist(app.renameTracker).name);
                    fprintf(app.summary, " (File Skipped)");
                    fprintf(app.summary, '\n');
                    fclose(app.summary);
                    app.filelist(app.renameTracker) = [];
                    app.renameTracker = app.renameTracker - 1;
                end
            end
            imshow(app.US_Image,'Parent',app.ImageAxes);

            % Create ptoential filename using OCR detected string from
            % ultrasound image and remove characters that could cause
            % saving issues in filesystems
            US_desc = ImageStringDetect(app);
            app.newFilename = strcat(app.info.PatientID, "_", US_desc);
            BadCharacterRemoval(app);

            % Update the GUI with information about the overall renaming
            % process.
            %   PatientID: patient identifier from DICOM meta-data which
            %   will be used as the prefix to rename the file
            %   CurrentImageText: Current images out of total images to give
            %   indicator of renaming progress
            %   TotalImages: Total images to be renamed
            %   ImageName: Existing filename of image to rename
            app.ParticipantIDTextArea.Value = app.info.PatientID;
            app.CurrentImageTextArea.Value = num2str(app.renameTracker);
            app.TotalImagesTextArea.Value = num2str(length(app.filelist));
            app.ImageNameTextArea.Value = app.filelist(app.renameTracker).name;

            % Update the GUI with information about the new name
            % OCRImageName: Suggested name based on text identified in
            % image using the optical character recognition tool
            % NewImageNameEdit: Suggested start of new name using
            % ParticipantID, to be used in cases where OCR fails / no text
            % is present on the image to use
            app.OCRImageNameTextArea.Value = app.newFilename;
            app.NewImageNameEditField.Value = strcat(app.info.PatientID, "_");
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % Description: Provides functionality to navigate to
            % next image in the overall filelist without having to
            % rename/save existing file

            if(app.renameTracker == length(app.filelist))
                app.renameTracker = 1;
            else
                app.renameTracker = app.renameTracker + 1;
            end
            
            % Open current ultrasound/DICOM image to get meta-data (info),
            % image data (US_Image), and show image data in GUI
            fileLen = length(app.filelist);
            while(app.renameTracker < fileLen)
                try
                    if(ispc)
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "\", app.filelist(app.renameTracker).name);
                    else
                        app.fileName = strcat(app.filelist(app.renameTracker).folder, "/", app.filelist(app.renameTracker).name);
                    end

                    app.info = dicominfo(app.fileName);
                    app.US_Image = dicomread(app.info);
                    break;
                catch
                    warning(strcat('Problem opening: ', app.fileName));
                    app.summary = fopen("summary.txt", 'at+' );
                    fprintf(app.summary, "Error Opening: ");
                    fprintf(app.summary, app.filelist(app.renameTracker).name);
                    fprintf(app.summary, " (File Skipped)");
                    fprintf(app.summary, '\n');
                    fclose(app.summary);
                    app.filelist(app.renameTracker) = [];
                    fileLen = fileLen - 1;
                end
            end
            imshow(app.US_Image,'Parent',app.ImageAxes);

            % Create ptoential filename using OCR detected string from
            % ultrasound image and remove characters that could cause
            % saving issues in filesystems
            US_desc = ImageStringDetect(app);
            app.newFilename = strcat(app.info.PatientID, "_", US_desc);
            BadCharacterRemoval(app);

            % Update the GUI with information about the overall renaming
            % process.
            %   PatientID: patient identifier from DICOM meta-data which
            %   will be used as the prefix to rename the file
            %   CurrentImageText: Current images out of total images to give
            %   indicator of renaming progress
            %   TotalImages: Total images to be renamed
            %   ImageName: Existing filename of image to rename
            app.ParticipantIDTextArea.Value = app.info.PatientID;
            app.CurrentImageTextArea.Value = num2str(app.renameTracker);
            app.TotalImagesTextArea.Value = num2str(length(app.filelist));
            app.ImageNameTextArea.Value = app.filelist(app.renameTracker).name;

            % Update the GUI with information about the new name
            % OCRImageName: Suggested name based on text identified in
            % image using the optical character recognition tool
            % NewImageNameEdit: Suggested start of new name using
            % ParticipantID, to be used in cases where OCR fails / no text
            % is present on the image to use
            app.OCRImageNameTextArea.Value = app.newFilename;
            app.NewImageNameEditField.Value = strcat(app.info.PatientID, "_");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UltrasoundRenameToolUIFigure = uifigure('Visible', 'off');
            app.UltrasoundRenameToolUIFigure.Name = 'Ultrasound Rename Tool';
            app.ImageAxes = uiaxes(app.UltrasoundRenameToolUIFigure);
            title(app.ImageAxes, 'Ultrasound Image to Rename:')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.SelectUltrasoundDirectoryButton = uibutton(app.UltrasoundRenameToolUIFigure, 'push');
            app.SelectUltrasoundDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUltrasoundDirectoryButtonPushed, true);
            app.SelectUltrasoundDirectoryButton.Text = 'Select Ultrasound Directory';
            app.RenameButton = uibutton(app.UltrasoundRenameToolUIFigure, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename';
            app.TotalImagesTextAreaLabel = uilabel(app.UltrasoundRenameToolUIFigure);
            app.TotalImagesTextAreaLabel.Text = 'Total Images:';
            app.TotalImagesTextArea = uitextarea(app.UltrasoundRenameToolUIFigure);
            app.CurrentImageTextAreaLabel = uilabel(app.UltrasoundRenameToolUIFigure);
            app.CurrentImageTextAreaLabel.Text = 'Current Image:';
            app.CurrentImageTextArea = uitextarea(app.UltrasoundRenameToolUIFigure);
            app.ParticipantIDTextAreaLabel = uilabel(app.UltrasoundRenameToolUIFigure);
            app.ParticipantIDTextAreaLabel.Text = 'Participant ID:';
            app.ParticipantIDTextArea = uitextarea(app.UltrasoundRenameToolUIFigure);
            app.NewImageNameLabel = uilabel(app.UltrasoundRenameToolUIFigure);
            app.NewImageNameLabel.Text = 'New Image Name:';
            app.NewImageNameEditField = uieditfield(app.UltrasoundRenameToolUIFigure, 'text');
            app.ImageNameTextAreaLabel = uilabel(app.UltrasoundRenameToolUIFigure);
            app.ImageNameTextAreaLabel.Text = 'Image Name:';
            app.ImageNameTextArea = uitextarea(app.UltrasoundRenameToolUIFigure);
            app.OCRImageNameLabel = uilabel(app.UltrasoundRenameToolUIFigure);
            app.OCRImageNameLabel.Text = 'OCR Image Name:';
            app.OCRImageNameTextArea = uitextarea(app.UltrasoundRenameToolUIFigure);
            app.ShowROICheckBox = uicheckbox(app.UltrasoundRenameToolUIFigure);
            app.ShowROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROICheckBoxValueChanged, true);
            app.ShowROICheckBox.Text = 'Show ROI';
            app.ReviseROIButton = uibutton(app.UltrasoundRenameToolUIFigure, 'push');
            app.ReviseROIButton.ButtonPushedFcn = createCallbackFcn(app, @ReviseROIButtonPushed, true);
            app.ReviseROIButton.Text = 'Revise ROI';
            app.BackButton = uibutton(app.UltrasoundRenameToolUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.UltrasoundRenameToolUIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            % Show the figure after all components are created
            app.UltrasoundRenameToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = US_DICOM_Renamer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UltrasoundRenameToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UltrasoundRenameToolUIFigure)
        end
    end
end